classdef SNATCH_IT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Container                     matlab.ui.container.Panel
        SettingsPanel                 matlab.ui.container.Panel
        LengthSpinner                 matlab.ui.control.Spinner
        LengthLabel                   matlab.ui.control.Label
        ModeLabel                     matlab.ui.control.Label
        ModeDD                        matlab.ui.control.DropDown
        SettingsLabel                 matlab.ui.control.Label
        HoardPanel                    matlab.ui.container.Panel
        NewGameButton                 matlab.ui.control.Button
        RemainingTilesEditField       matlab.ui.control.EditField
        RemainingTilesEditFieldLabel  matlab.ui.control.Label
        NewWordDD                     matlab.ui.control.DropDown
        NewTileButton                 matlab.ui.control.Button
        NewWordEditField              matlab.ui.control.EditField
        ValidWordLamp                 matlab.ui.control.Lamp
        HoardLabel                    matlab.ui.control.Label
        PoolPanel                     matlab.ui.container.Panel
        PoolLabel                     matlab.ui.control.Label
    end


    properties (Access = private)
    tileBag     % cell array to hold tiles
    pool        % cell array to hold revealed tiles
    hoard       % cell array to hold 'hoard' of words
    dictionary  % cell array to hold legal/permitted English words

end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @WindowSizeChanged, true);
            
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @PressEnter, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @PressSpace, true);
        end


%== LOAD ENABLE DICTIONARY. 
function loadDictionary(app)

    % Located in the directory, but any user needs to access/have it found
    % Use this to determine if user's new word / stolen word is valid
                   
    % Get directory of the current .mlapp file
    appDir = fileparts(mfilename('fullpath'));
        
    % Define dictionary file path relative to the app directory
    dictionaryFile = fullfile(appDir, 'ENABLE_dictionary.txt');
        
    % Check if the dictionary file exists
    if exist(dictionaryFile, 'file') ~= 2
        uialert(app.UIFigure, 'Dictionary file not found!', 'File Error');
        return;
    end
        
    % Read dictionary file
    fileID = fopen(dictionaryFile, 'r');
    rawDictionary = textscan(fileID, '%s');
    fclose(fileID);
        
    % Store the dictionary words in a cell array
    app.dictionary = rawDictionary{1};        
end %end of loadDictionary%


%=== ADD WORD TO HOARD AFTER SUCESSFUL SUBMISSION
function submitWord(app, word) % UPDATED:8/2/24
    %=== SUBMIT WORD FOR VALIDATION (with return key)

    % 3 possible errors upon check: #1 below minimum length. #2 not using available
    % letters. #3 not valid in dictionary. 
    % Word must satisfy all parameters to reach hoard.
    
    
    %= Attempt to validate word. UPDATED:8/2/24
    validationStatus = validateWord(app, word);

    switch validationStatus
        case 'valid' % no error; satisfies criteria. Add word, lamp green
            addToHoard(app, word); % add word ONLY if checks passed
            showError(app, '', 'green');

        case 'too short'
            showError(app, 'Word must be minimum length', 'yellow');

        case 'bad letters'
            showError(app, 'Use only letters in pool and/or hoard', 'orange');

        case 'not in dict'
            showError(app, 'Word not in dictionary', 'red');
    end
end %end of submitWord%


function validationStatus = validateWord(app, word) %UPDATED:8/2/24
    %=== VALIDATE WORD BY PASSING 3 CHECKS: 
    % 1)length 2)appropriate letters used 3)legal according to dictionary

    % PREPARATION
    word = upper(word);             % Convert word to uppercase
    letters = cellstr(word');       % separate letters of word to test
    availableLetters = app.pool;    % pool letters (add for steal/new)


%=== 1) CHECK MINIMUM LENGTH
    if length(word) < app.LengthSpinner.Value
        validationStatus = 'too short';
        return;
    end


%=== 2) CHECK APPROPRIATE LETTERS USED    
    % for NEW words:
    if strcmp(app.NewWordDD.Value, 'NEW WORD')
        for ii = 1:length(letters)
            idx = find(strcmp(availableLetters, letters{ii}), 1);
            if isempty(idx)
                validationStatus = 'bad letters';
                return;
            end
            availableLetters(idx) = []; % remove used letter from available
        end


    % for STEAL words:
    elseif strcmp(app.NewWordDD.Value, 'STEAL WORD')
        % Find a word in the hoard that could be transformed
        for ii = 1:numel(app.hoard)
            hoardWord = app.hoard{ii};
            
            if app.canSteal(hoardWord, letters, app.pool)
                canSteal = true;
                app.hoard{ii} = word;    % Replace  hoard word with new steal
                break;
            end
        end

        if ~canSteal %if no hoard word can be stolen
            validationStatus = 'bad letters';
            return;
        end    
    end


%=== 3) CHECK SUBMITTED WORD IN DICTIONARY
    if ~any(strcmpi(app.dictionary, word))
        validationStatus = 'not in dict';
        return
    end


%=== MOVE FORWARD IF ALL 3 CHECKS PASS
    validationStatus = 'valid';

end


function addToHoard(app, word)
%=== ADD WORD TO HOARD IF CHECKS PASSED. 
% **should only be used for 'new' **CONFIRM THIS WORKS

    app.hoard{end+1} = word;
end %end of addToHoard


function canSteal = canSteal(app, hoardWord, letters, pool)
    %== HELPER FUNCTION TO DETERMINE IF A 'STEAL' CAN BE PERFORMED
    hoardLetters = cellStr(hoardWord');
    remainingLetters = letters; %letters of submitted word not found in hoard


    % Do all a hoard word's letters fit in proposed word?
    for ii = 1:length(hoardLetters)
    idx = find(strcmp(remainingLetters, hoardLetters{ii}), 1);
        if ~isempty(idx)
            remainingLetters(idx) = [];
        else
            canSteal = false;
            return;
        end
    end

    % Are remaining letters in pool?
    for jj = 1:length(remainingLetters)
        idx = find(strcmp(pool, remainingLetters{jj}), 1);
        if isempty(idx)
            canSteal = false;
            return;
        end
    end

    canSteal = true; %if can be stolen  
 %end of canSteal        
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
    
%== START NEW GAME, USING SELECTED GAME MODE TO DECIDE TILE SET

% Initialize tile bag as an empty cell array
app.tileBag = {};

% Define letter counts for each game mode
selectedSize = app.ModeDD.Value;

switch selectedSize
    case 'Default' 
        % 144 tiles, same as Bananagrams set
        letter_count = struct('E',18, 'A',13, 'I',12, 'O',11, 'T',9, 'R',9, ...
            'N',8, 'D',6, 'S',6, 'U',6, 'L',5, 'G',4, 'B',3, 'C',3, 'F',3, ...
            'H',3, 'M',3, 'P',3, 'V',3, 'W',3, 'Y',3, 'J',2, 'K',2, 'Q',2, ...
            'X',2, 'Z',2);

    case 'Mini'
        % 78 tiles
        letter_count = struct('E',9, 'A',7, 'I',6, 'O',5, 'T',5, 'R',5, ...
            'N',4, 'D',3, 'S',3, 'U',3, 'L',3, 'G',2, 'B',2, 'C',2, 'F',2, ...
            'H',2, 'M',2, 'P',2, 'V',2, 'W',2, 'Y',2, 'J',1, 'K',1, 'Q',1, ...
            'X',1, 'Z',1);

    case 'Wild' % FIX THIS FIX THIS
        % 144 randomized tiles 
        % updated 8/2/24
        letter_count = generate_wild_bag();

    case 'X-Small'
        % 8 tiles, for testing purposes
        letter_count = struct('E',2, 'A',2, 'T',1, 'R',1, 'D',1, 'S',1);

    otherwise
        error('Unknown tile bag size selected');
end


% Prepare letters from selected game mode
letters = fieldnames(letter_count);

for i = 1:numel(letters)
    letter = letters{i};
    count = letter_count.(letter);
    app.tileBag = [app.tileBag, repmat({letter}, 1, count)];
end


% Display the number of remaining tiles
app.RemainingTilesEditField.Value = num2str(numel(app.tileBag));

% Initialize the pool and hoard (will also reset after prev
% game)
app.pool = {};
app.hoard = {};
delete(app.PoolPanel.Children);


        end

        % Button pushed function: NewTileButton
        function NewTileButtonPushed(app, event)
    %updated 8/2/24. Still needs to clear once new game starts. Need to
    %better space out letters

    %== EACH PUSH ADDS A RANDOM LETTER FROM THE TILE BAG TO THE POOL
    % how many letters may be in pool before user asked to play word
    max_pool = 4*(app.LengthSpinner.Value+1);

    % if any letters are left, add a tile
    if ~isempty(app.tileBag) && numel(app.pool)<max_pool
        % Randomly select letter from tile bag
        idx = randi(numel(app.tileBag));
        newTile = app.tileBag{idx};

        % Add random tile to pool
        app.pool{end+1} = newTile;
       
        % Remove selected tile from tile bag
        app.tileBag(idx) = [];

        % Display updated number of remaining tiles
        app.RemainingTilesEditField.Value = num2str(numel(app.tileBag));
        

        % Display new tile in pool panel, with space from previous
        % Define the number of items per column
        itemsPerColumn = 4;
        columnWidth = 30;
        rowHeight = 18;


        % Calculate the position for the new label
        column = floor((numel(app.pool) - 1) / itemsPerColumn);
        row = mod((numel(app.pool) - 1), itemsPerColumn);

        xPos = 30 + column * columnWidth;
        yPos = 150 - row * rowHeight;

        uilabel(app.PoolPanel, 'Text', newTile, 'Position', [xPos, yPos, columnWidth, rowHeight]);
        

    elseif isempty(app.tileBag)
        % If empty but button pressed, display a message if there are no tiles left in the tile bag
        uialert(app.UIFigure, 'NO MORE TILES REMAINING', 'Tile Bag Empty');
    
        % ** error hopefully not necessary, as empty bag should ->
        % end of game page

    elseif numel(app.pool)>=max_pool
        % error if user spams new tiles without making new words
        uialert(app.UIFigure, 'MUST PLAY NEW WORD', 'Temporary Limit Reached');
    
    end

end


%=== FUNCTION TO CHECK IF A WORD IS VALID
% 1) must be in dictionary and 2) must be minimum length selected by uwer
function isValid = isValidWord(app, word)
    isValid = any(strcmpi(app.dictionary, word)) && length(word) >= app.LengthSpinner.Value;
        
    % once established as valid here, letters will be compared to pool,
    % hoard, etc.

        end

        % Value changed function: NewWordEditField
        function NewWordEditFieldValueChanged(app, event)
            % NOT CERTAIN ABOUT THIS FIX??
    
            word = app.NewWordEditField.Value;
            if ~isempty(word)
                app.submitWord(word);
            end
        end

        % Callback function: not associated with a component
        function PressEnter(app, event)
            % 'ENTER' KEY TRIGGERS WORD SUBMISSION
            
            if strcmp(event.Key, 'return') % Check if Enter key is pressed
                NewWord = app.NewWordEditField.Value;
                submitWord(app, NewWord);
            end

                    
        end

        % Callback function: not associated with a component
        function PressSpace(app, event)
            % 'SPACE' KEY TRIGGERS NEW TILE (just like button push)

            if strcmp(event.Key, 'space') % check if Space key is pressed
                NewTileButtonPushed(app, []);
            end
        end

        % Size changed function: UIFigure
        function WindowSizeChanged(app, event)
            % establish desire width, height, ratio
            figWidth = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);
            
            aspectRatio = 644 / 438; % Desired aspect ratio
            
            % lock aspect ratio if size changed
            if figWidth / figHeight > aspectRatio
                newWidth = figHeight * aspectRatio;
                app.UIFigure.Position(3) = newWidth;
            else
                newHeight = figWidth / aspectRatio;
                app.UIFigure.Position(4) = newHeight;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @WindowSizeChanged, true);
            app.Container = uipanel(app.UIFigure);
            app.PoolPanel = uipanel(app.Container);
            app.PoolLabel = uilabel(app.PoolPanel);
            app.PoolLabel.Text = 'POOL';
            app.HoardPanel = uipanel(app.Container);
            app.HoardLabel = uilabel(app.HoardPanel);
            app.HoardLabel.Text = 'HOARD';
            app.ValidWordLamp = uilamp(app.HoardPanel);
            app.NewWordEditField = uieditfield(app.HoardPanel, 'text');
            app.NewWordEditField.ValueChangedFcn = createCallbackFcn(app, @NewWordEditFieldValueChanged, true);
            app.NewTileButton = uibutton(app.HoardPanel, 'push');
            app.NewTileButton.ButtonPushedFcn = createCallbackFcn(app, @NewTileButtonPushed, true);
            app.NewTileButton.Text = 'NEW TILE';
            app.NewWordDD = uidropdown(app.HoardPanel);
            app.NewWordDD.Items = {'NEW WORD', 'STEAL'};
            app.NewWordDD.Placeholder = 'NEW WORD';
            app.NewWordDD.Value = 'NEW WORD';
            app.RemainingTilesEditFieldLabel = uilabel(app.HoardPanel);
            app.RemainingTilesEditFieldLabel.Text = 'Remaining Tiles';
            app.RemainingTilesEditField = uieditfield(app.HoardPanel, 'text');
            app.NewGameButton = uibutton(app.HoardPanel, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'NEW GAME';
            app.SettingsPanel = uipanel(app.Container);
            app.SettingsLabel = uilabel(app.SettingsPanel);
            app.SettingsLabel.Text = 'SETTINGS';
            app.ModeDD = uidropdown(app.SettingsPanel);
            app.ModeDD.Items = {'Default', 'Mini', 'Wild', 'X-Small'};
            app.ModeDD.Value = 'Default';
            app.ModeLabel = uilabel(app.SettingsPanel);
            app.ModeLabel.Text = 'GAME MODE';
            app.LengthLabel = uilabel(app.SettingsPanel);
            app.LengthLabel.Text = 'MINIMUM LENGTH';
            app.LengthSpinner = uispinner(app.SettingsPanel);
            app.LengthSpinner.Limits = [3 7];
            app.LengthSpinner.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNATCH_IT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end